classdef sigcompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image1Panel             matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        Rotate1Button           matlab.ui.control.Button
        BlurSDEditFieldLabel    matlab.ui.control.Label
        BlurSDEditField         matlab.ui.control.NumericEditField
        Rectangle1Button        matlab.ui.control.Button
        Image2Panel             matlab.ui.container.Panel
        Rotate2Button           matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        BlurSDEditField_2Label  matlab.ui.control.Label
        BlurSDEditField_2       matlab.ui.control.NumericEditField
        Rectangle2Button        matlab.ui.control.Button
    end

    
    properties (Access = private)
        I1 % First image
        I2 % Second image
        I1_original
        I2_original
        n_filter
        X_filter
        Y_filter      
        sigfun
        sig1
        sig2
        F1
        F2
    end
    
    methods (Access = private)
        
        function display_images(app, varargin)
            if nargin == 1
                idx = [1 2];
            else
                idx = varargin{1};
            end
            if ismember(1, idx)
                app.sig1 = app.sigfun(app.I1);
                imagesc(app.UIAxes, app.I1, [0 1]);
                colormap(app.UIAxes, gray(65536));
                set(app.UIAxes, 'visible', 'off');
            end
            if ismember(2, idx)
                app.sig2 = app.sigfun(app.I2);
                imagesc(app.UIAxes2, app.I2, [0 1]);
                colormap(app.UIAxes2, gray(65536));
                set(app.UIAxes2, 'visible', 'off')
            end
        end
        
        function K = makefilter(app, sigma)
            size(app.X_filter)
            size(app.Y_filter)
            K = exp(-(abs(app.X_filter).^2 + abs(app.Y_filter).^2) ./ (2*sigma^2));
            K = K / sum(K(:));
        end
        
        function app = compute_signatures(app)
        app.sig1 = app.sigfun(app.I1);
        app.sig2 = app.sigfun(app.I2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imfile1, imfile2)
            app.I1_original = double(rgb2gray(imread(imfile1)))/255;
            app.I2_original = double(rgb2gray(imread(imfile2)))/255;
            app.I1 = app.I1_original;
            app.I2 = app.I2_original;
            app.F1 = app.I1;
            app.F2 = app.I2;
            app.n_filter = 500;
            app.sigfun = @SA2_signature;
            
            % Set up filter domain
            x_filt = -app.n_filter:app.n_filter;
            y_filt = x_filt; % for now
            [app.X_filter, app.Y_filter] = meshgrid(x_filt, y_filt);
            
            % display images
            app = app.compute_signatures();
            display_images(app)
            imagesc(app.UIAxes2, app.I2);
            colormap(app.UIAxes2, gray(65536));
            
        end

        % Button pushed function: Rotate1Button
        function Rotate1ButtonPushed(app, event)
            app.I1 = flipud(app.I1');
            app.I1_original = flipud(app.I1_original');
            display_images(app, 1);
        end

        % Button pushed function: Rotate2Button
        function Rotate2ButtonPushed(app, event)
            app.I2 = flipud(app.I2');
            app.I2_original = flipud(app.I2_original');
            display_images(app, 2);
        end

        % Value changed function: BlurSDEditField
        function BlurSDEditFieldValueChanged(app, event)
            value = app.BlurSDEditField.Value;
            if value == 0
                app.I1 = app.I1_original;
            else
                 K = app.makefilter(value);
                app.I1 = conv_fft2(app.I1_original, K, 'same');
            end
            app.display_images(1)
            
        end

        % Value changed function: BlurSDEditField_2
        function BlurSDEditField_2ValueChanged(app, event)
            value = app.BlurSDEditField_2.Value;
            if value == 0
                app.I2 = app.I2_original;
            else
                K = app.makefilter(value);
                app.I2 = conv_fft2(app.I2_original, K, 'same');
            end
            app.display_images(2)
        end

        % Button pushed function: Rectangle1Button
        function Rectangle1ButtonPushed(app, event)
            [X, Y] = ginput(2);
            X = round(X);
            Y = round(Y);
            X = max(1, X);
            X = min(X, size(app.I1, 2));
            Y = max(1, Y);
            Y = min(Y, size(app.I1, 1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image1Panel = uipanel(app.UIFigure);
            app.Image1Panel.Title = 'Image 1';
            app.UIAxes = uiaxes(app.Image1Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Rotate1Button = uibutton(app.Image1Panel, 'push');
            app.Rotate1Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate1ButtonPushed, true);
            app.Rotate1Button.Text = 'Rotate1';
            app.BlurSDEditFieldLabel = uilabel(app.Image1Panel);
            app.BlurSDEditFieldLabel.Text = 'Blur SD';
            app.BlurSDEditField = uieditfield(app.Image1Panel, 'numeric');
            app.BlurSDEditField.Limits = [0 100];
            app.BlurSDEditField.ValueChangedFcn = createCallbackFcn(app, @BlurSDEditFieldValueChanged, true);
            app.Rectangle1Button = uibutton(app.Image1Panel, 'push');
            app.Rectangle1Button.ButtonPushedFcn = createCallbackFcn(app, @Rectangle1ButtonPushed, true);
            app.Rectangle1Button.Text = 'Rectangle1';
            app.Image2Panel = uipanel(app.UIFigure);
            app.Image2Panel.Title = 'Image 2';
            app.Rotate2Button = uibutton(app.Image2Panel, 'push');
            app.Rotate2Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate2ButtonPushed, true);
            app.Rotate2Button.Text = 'Rotate2';
            app.UIAxes2 = uiaxes(app.Image2Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.BlurSDEditField_2Label = uilabel(app.Image2Panel);
            app.BlurSDEditField_2Label.Text = 'Blur SD';
            app.BlurSDEditField_2 = uieditfield(app.Image2Panel, 'numeric');
            app.BlurSDEditField_2.Limits = [0 100];
            app.BlurSDEditField_2.ValueChangedFcn = createCallbackFcn(app, @BlurSDEditField_2ValueChanged, true);
            app.Rectangle2Button = uibutton(app.Image2Panel, 'push');
            app.Rectangle2Button.Text = 'Rectangle2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigcompare(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end